classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EditField           matlab.ui.control.EditField
        DataEditField       matlab.ui.control.EditField
        DataEditFieldLabel  matlab.ui.control.Label
        DecryptVideoButton  matlab.ui.control.Button
        EncryptVideoButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function results = processVideo(app,event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            a=VideoReader(filename);
            vidFrame = readFrame(a);
            imshow(vidFrame);
            pause(1/a.FrameRate);
            results="Done"
        end
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DataEditField
        function DataEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: EncryptVideoButton
        function EncryptVideoButtonPushed(app, event)

            processedResult=processVideo(app,app.DataEditField.Value);
            app.EditField.Value=processedResult;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EncryptVideoButton = uibutton(app.UIFigure, 'push');
            app.EncryptVideoButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptVideoButtonPushed, true);
            app.EncryptVideoButton.Text = 'Encrypt Video';
            app.DecryptVideoButton = uibutton(app.UIFigure, 'push');
            app.DecryptVideoButton.Text = 'Decrypt Video';
            app.DataEditFieldLabel = uilabel(app.UIFigure);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.UIFigure, 'text');
            app.DataEditField.ValueChangedFcn = createCallbackFcn(app, @DataEditFieldValueChanged, true);
            app.DataEditField.Placeholder = 'Enter the data';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end